classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConnectedComponentAnalysisofSatelliteImagesUIFigure  matlab.ui.Figure
        ConnectedComponentAnalysisofSatelliteImagesLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        SelectImageDropDown       matlab.ui.control.DropDown
        SelectImageDropDownLabel  matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        UIAxes_5                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function RunButtonPushed(app, event)
            
        end

        % Value changed function: SelectImageDropDown
        function SelectImageDropDownValueChanged(app, event)
            value = app.SelectImageDropDown.Value;
             global a
            if strcmp(value,'Image 1') 
                fullpathname=strcat('C:\Users\JUNE\Desktop\Micro Project\','Lake.png'); 
                a=imread(fullpathname);                 
                app.Image.ImageSource=fullpathname;
            end 
            if strcmp(value,'Image 2')               
                fullpathname=strcat('C:\Users\JUNE\Desktop\Micro Project\','Lake2.jpg'); 
                a=imread(fullpathname);                 
                app.Image.ImageSource=fullpathname;                 
            end 
            if strcmp(value,'Image 3')  
                fullpathname=strcat('C:\Users\JUNE\Desktop\Micro Project\','greatlake.jpg'); 
                a=imread(fullpathname);                 
                app.Image.ImageSource=fullpathname; 
            end 
        end

        % Button pushed function: RunButton
        function RunButtonPushed2(app, event)
             global a 
             global thres
             global n_area
             value_dd = string(app.SelectImageDropDown.Value);
            if value_dd=="Image 1" 
                thres=0.35;
                n_area=1;
                fullpathname=strcat('C:\Users\JUNE\Desktop\Micro Project\','Lake.png'); 
                a=imread(fullpathname);                 
                gray_img = rgb2gray(a);
                imshow(gray_img,'parent',app.UIAxes_2);
                heqgray = histeq(gray_img);
                imshow(heqgray,'parent',app.UIAxes_3);
                BWimg = imbinarize(heqgray,thres);
                imshow(BWimg,'parent',app.UIAxes_5);
                BW_inverted=imcomplement(BWimg);
                imshow(BW_inverted,'parent',app.UIAxes_4);                
                out_img = bwareafilt(BW_inverted,n_area);
                imshow(out_img,'parent',app.UIAxes); 

            elseif value_dd=="Image 2" 
                thres=0.28;
                n_area=5;
                fullpathname=strcat('C:\Users\JUNE\Desktop\Micro Project\','Lake2.jpg'); 
                a=imread(fullpathname);                 
                gray_img = rgb2gray(a);
                imshow(gray_img,'parent',app.UIAxes_2);
                heqgray = histeq(gray_img);
                imshow(heqgray,'parent',app.UIAxes_3);
                BWimg = imbinarize(heqgray,thres);
                imshow(BWimg,'parent',app.UIAxes_5);
                BW_inverted=imcomplement(BWimg); 
                imshow(BW_inverted,'parent',app.UIAxes_4);
                out_img = bwareafilt(BW_inverted,n_area);
                imshow(out_img,'parent',app.UIAxes); 

            elseif value_dd=="Image 3" 
                thres=0.28;
                n_area=3;
                fullpathname=strcat('C:\Users\JUNE\Desktop\Micro Project\','greatlake.jpg'); 
                a=imread(fullpathname);                 
                gray_img = rgb2gray(a);
                imshow(gray_img,'parent',app.UIAxes_2);
                heqgray = histeq(gray_img);
                imshow(heqgray,'parent',app.UIAxes_3);
                BWimg = imbinarize(heqgray,thres);
                imshow(BWimg,'parent',app.UIAxes_5);
                BW_inverted=imcomplement(BWimg);
                imshow(BW_inverted,'parent',app.UIAxes_4);
                out_img = bwareafilt(BW_inverted,n_area);
                imshow(out_img,'parent',app.UIAxes); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConnectedComponentAnalysisofSatelliteImagesUIFigure = uifigure('Visible', 'off');
            app.ConnectedComponentAnalysisofSatelliteImagesUIFigure.Name = 'Connected Component Analysis of Satellite Images';
            app.UIAxes = uiaxes(app.ConnectedComponentAnalysisofSatelliteImagesUIFigure);
            title(app.UIAxes, 'Extracted Water Bodies')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.ConnectedComponentAnalysisofSatelliteImagesUIFigure);
            title(app.UIAxes_2, 'Gray Scale Image')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.ConnectedComponentAnalysisofSatelliteImagesUIFigure);
            title(app.UIAxes_3, 'Histogram Equilization')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.ConnectedComponentAnalysisofSatelliteImagesUIFigure);
            title(app.UIAxes_4, 'Inverted Binary Image')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.ConnectedComponentAnalysisofSatelliteImagesUIFigure);
            title(app.UIAxes_5, 'Binary Image')
            zlabel(app.UIAxes_5, 'Z')
            app.RunButton = uibutton(app.ConnectedComponentAnalysisofSatelliteImagesUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed2, true);
            app.RunButton.Text = 'Run';
            app.SelectImageDropDownLabel = uilabel(app.ConnectedComponentAnalysisofSatelliteImagesUIFigure);
            app.SelectImageDropDownLabel.Text = 'Select Image';
            app.SelectImageDropDown = uidropdown(app.ConnectedComponentAnalysisofSatelliteImagesUIFigure);
            app.SelectImageDropDown.Items = {'Image 1', 'Image 2', 'Image 3'};
            app.SelectImageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectImageDropDownValueChanged, true);
            app.SelectImageDropDown.Value = 'Image 1';
            app.Image = uiimage(app.ConnectedComponentAnalysisofSatelliteImagesUIFigure);
            app.Image.ImageSource = 'Lake.png';
            app.ConnectedComponentAnalysisofSatelliteImagesLabel = uilabel(app.ConnectedComponentAnalysisofSatelliteImagesUIFigure);
            app.ConnectedComponentAnalysisofSatelliteImagesLabel.Text = 'Connected Component Analysis of Satellite Images';
            % Show the figure after all components are created
            app.ConnectedComponentAnalysisofSatelliteImagesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConnectedComponentAnalysisofSatelliteImagesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConnectedComponentAnalysisofSatelliteImagesUIFigure)
        end
    end
end